classdef Behaviour_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BehaviourToolboxUIFigure        matlab.ui.Figure
        LoadExistingAnalysisButton      matlab.ui.control.Button
        StartNewAnalysisButton          matlab.ui.control.Button
        SaveAnalysisButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SelectBrowseROIsButton          matlab.ui.control.Button
        ShowAnalyzeButton               matlab.ui.control.Button
        RecalculateallCheckBox          matlab.ui.control.CheckBox
        UpdateTableButton               matlab.ui.control.Button
        PauseforeachexperimentCheckBox  matlab.ui.control.CheckBox
        ExperimentsListBoxLabel         matlab.ui.control.Label
        ExperimentsListBox              matlab.ui.control.ListBox
        RefreshFilelistButton           matlab.ui.control.Button
        ExportToMatlabanalysisButton    matlab.ui.control.Button
        RecoverinterruptedanalysisButton  matlab.ui.control.StateButton
        IfyoupressedCtrlCbutwantrecoverwhateverwasalreadyanalysedLabel  matlab.ui.control.Label
        OpenFolderButton                matlab.ui.control.Button
        OpenVideoButton                 matlab.ui.control.Button
    end


    properties (Access = public)
        Video_path
        Experiment_set % Description
        failed_analysis
        failed_loading
        splitvideo
        full_table
        expe_table
        current_selection_file
        current_selection_folder
        current_selection_idx
    end


    methods (Access = private)
    
        function enable_analysis(app, status)
            if status
                status = 'On';
            else
                status = 'Off';
            end
            
            app.UITable.Enable = status;
            app.SelectBrowseROIsButton.Enable = status;
            app.ShowAnalyzeButton.Enable = status;
            app.RecalculateallCheckBox.Enable = status;
            app.SaveAnalysisButton.Enable = status;
            app.UpdateTableButton.Enable = status;
            app.PauseforeachexperimentCheckBox.Enable = status;
            app.ExperimentsListBoxLabel.Enable = status;
            app.ExperimentsListBox.Enable = status;
            app.ExportToMatlabanalysisButton.Enable = 'Off';%status;
        end

        function [filter_list, value, selection, subset] = get_subset(app)
            if numel(app.ExperimentsListBox.Value) ==  numel(app.ExperimentsListBox.Items)
                filter_list = '';
                value = 1:numel(app.ExperimentsListBox.Items);
                selection = app.ExperimentsListBox.Value;
            else
                selection = app.ExperimentsListBox.Value;
                value = find(ismember(app.ExperimentsListBox.Items, app.ExperimentsListBox.Value));
                match = ismember(app.full_table(:,9),selection);
                list = app.full_table(match,1:2);
                filter_list = unique(strrep(join(strcat(list,'/'), 2), ' ', ''));
            end
            
            %% When no filter, use everything
            if isempty(value)
                value = 1:app.Experiment_set.n_expe;
            end 
            
            %% Generate an experiment subset to pass
            subset = app.Experiment_set;
            subset.experiments = subset.experiments(value);  
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', 'behaviour_GUI', app);
        end

        % Button pushed function: LoadExistingAnalysisButton
        function LoadExistingAnalysisButtonPushed(app, event)
            [selection_file, selection_folder] = uigetfile('Load previous analysis','*.mat');
            if any(selection_file)
                app.Experiment_set = load([selection_folder, '/', selection_file]);
                app.Video_path     = app.Experiment_set.Video_path;  
                app.Experiment_set = app.Experiment_set.analysis;
                app.enable_analysis(true);
            end
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: StartNewAnalysisButton
        function StartNewAnalysisButtonPushed(app, event)
            selection = uigetdir(app.Video_path, 'Select Video Folder');
            app.enable_analysis(false);
            if any(selection)
                app.Video_path = [selection, '\'];     
                app.enable_analysis(true);
                app.Experiment_set = [];
                app.ExperimentsListBox.Items = {};
                app.Experiment_set = Analysis_Set(app.Video_path);
                app.Experiment_set = app.Experiment_set.update();
                %[app.Experiment_set, ~, ~, invalid] = batch_select_video_ROIs(app.Experiment_set, '', '' ,'', '', false);
                % app.Experiment_set.experiments = app.Experiment_set.experiments(~invalid);
                app.SelectBrowseROIsButton.Enable = 'On';
            end
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: SaveAnalysisButton
        function SaveAnalysisButtonPushed(app, event)
            analysis = app.Experiment_set;
            Video_path     = app.Video_path;  
            uisave({'analysis','Video_path'},['saved_analysis ',strrep(datestr(datetime('now')),':','_')]);
        end

        % Button pushed function: SelectBrowseROIsButton
        function SelectBrowseROIsButtonPushed(app, event)
            if isempty(app.Video_path)
                app.StartNewAnalysisButtonPushed();
            end
            
            %% Get any subset of experiments that would be selected
            [filter_list, temp_subset_expe_idx, selection, subset] = get_subset(app);
            
            %% Select MI ROI for the current selection
            %try
                assignin('base','temp_subset_expe_idx', temp_subset_expe_idx); % that's for recovering data in case of interrupt
                [result] = batch_select_video_ROIs(subset, app.Experiment_set.default_tags, '' ,filter_list, '');
            %catch
             %   result = evalin('base','analysis');
           %end
            if isempty(result.experiments)
                error_box('Unable to load the selection. You may have moved the files. Try Refresh File List')
                return
            end

            app.Experiment_set.experiments(temp_subset_expe_idx) = result.experiments;
            
            %app.Experiment_set = evalin('base','all_experiments_output');
            
            %% Update full data table with any new values
            app.enable_analysis(true);
            app.UpdateTableButtonPushed();
            
            %% If there was an initial selection ,resore it
            if ~isempty(filter_list)
                app.ExperimentsListBox.Value = selection;
                app.ExperimentsListBoxValueChanged();
            end
            assignin('base', 'behaviour_GUI', app);
        end

        % Button pushed function: ShowAnalyzeButton
        function ShowAnalyzeButtonPushed(app, event)
            %% Get any subset of experiments that would be selected
            [filter_list, temp_subset_expe_idx, selection, subset] = get_subset(app);
            
            %% Measure MI for the current selection
            %try
                assignin('base','temp_subset_expe_idx',temp_subset_expe_idx); % that's for recovering data in case of interrupt
                [result , app.failed_analysis] = batch_measure_MIs_from_ROIs(subset, app.RecalculateallCheckBox.Value, true, app.PauseforeachexperimentCheckBox.Value);
            %catch
            %    result = evalin('base','analysis');
            %end
            app.Experiment_set.experiments(temp_subset_expe_idx) = result.experiments;
            app.UpdateTableButtonPushed();
            
            %% If there was an initial selection, restore it
            if ~isempty(filter_list)
                app.ExperimentsListBox.Value = selection;
                app.ExperimentsListBoxValueChanged();
            end
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            app.full_table = {};
            app.expe_table = {};
            
            for exp = 1:app.Experiment_set.n_expe
                current_experiment = app.Experiment_set.experiments(exp);
                exp_name = strsplit(current_experiment.path,'/');
                exp_name = strrep(join(strcat(exp_name(end-2:end),'/')), ' ', '');
                last_line_idx = size(app.full_table, 1);
                
                %% Update experiment list
                app.expe_table(end+1,1) = exp_name;
                
                %% Update recordings list
                for recording_idx = 1:current_experiment.n_rec
                    current_recording = current_experiment.recordings(recording_idx);
                    if current_recording.n_vid
                        new_line_idx = last_line_idx+recording_idx;
                        app.full_table{new_line_idx,7} = repmat(-1, 1, numel(current_experiment.videotypes)); % missing videos will stay at -1
                        at_least_one_selection = ~all(cellfun(@isempty, [current_recording.videos.ROI_location]));
                        for video_type_idx = 1:current_recording.n_vid
                            real_idx = find(contains({current_recording.videos.path}, current_recording.videotypes{video_type_idx}));
                            if ~isempty(real_idx)
                                current_video = current_recording.videos(real_idx);
                                name = strsplit(current_recording.videos(real_idx).path,{'/','\'});
                                table_real_idx = find(cellfun(@(x) contains(current_video.video_types,x),  app.UITable.ColumnName(4:6)'));
                                if at_least_one_selection%~isempty(current_video.motion_indexes) && ~any(cellfun(@isempty, current_video.motion_indexes))
                                    app.full_table{new_line_idx,7}(table_real_idx) = numel(current_video.ROI_location);%numel(current_video.motion_indexes);
                                    if current_video.n_roi == numel(current_video.motion_indexes) && ~any(cellfun(@isempty, current_video.motion_indexes))
                                        app.full_table{new_line_idx,8} = true;
                                    else
                                        app.full_table{new_line_idx,8} = false;
                                    end
                                else
                                    app.full_table{new_line_idx,7}(table_real_idx) = numel(current_video.ROI_location);
                                    app.full_table{new_line_idx,8} = false;
                                end
        
                                app.full_table(new_line_idx,1:3) = [name(end-4), name(end-3), {name{end-1}(8:15)}];
                                if contains(name(end), 'Eye')
                                    app.full_table(new_line_idx,4) = {any([app.full_table{new_line_idx,4}, 1])};
                                elseif any(contains(current_recording.videotypes, 'Eye'))
                                    app.full_table(new_line_idx,4) = {any([app.full_table{new_line_idx,4}, 0])};
                                end
                                if contains(name(end), 'Body')
                                    app.full_table(new_line_idx,5) = {any([app.full_table{new_line_idx,5}, 1])};
                                elseif any(contains(current_recording.videotypes, 'Body'))
                                    app.full_table(new_line_idx,5) = {any([app.full_table{new_line_idx,5}, 0])};
                                end
                                if contains(name(end), 'Whisk')
                                    app.full_table(new_line_idx,6) = {any([app.full_table{new_line_idx,6}, 1])};
                                elseif any(contains(current_recording.videotypes, 'Whisk'))
                                    app.full_table(new_line_idx,6) = {any([app.full_table{new_line_idx,6}, 0])};
                                end
                                
                                app.full_table(new_line_idx,9) = exp_name; %to match expe_table
                            end                            
                        end
                    else
                        fprintf([current_recording.path,' seems empty. consider cleaning folder\n'])
                    end
                end
            end
            if ~isempty(app.full_table)
                invalid = cellfun(@isempty, app.full_table(:,9));
                app.full_table = app.full_table(~invalid,:); %% QQ CHECK WHY
                app.full_table(:,7) = cellfun(@(x) num2str(x),app.full_table(:,7), 'UniformOutput', false);
                app.UITable.Data = app.full_table(:,1:8);
            else
                app.UITable.Data = [];
            end
            
            app.ExperimentsListBox.Items = app.expe_table;
            app.ExperimentsListBoxValueChanged();
        end

        % Value changed function: ExperimentsListBox
        function ExperimentsListBoxValueChanged(app, event)
            value = app.ExperimentsListBox.Value;
            match = ismember(app.full_table(:,9),value);
            app.UITable.Data = app.full_table(match,1:8);
        end

        % Button pushed function: RefreshFilelistButton
        function RefreshFilelistButtonPushed(app, event)
            app.Experiment_set = app.Experiment_set.update();
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: ExportToMatlabanalysisButton
        function ExportToMatlabanalysisButtonPushed(app, event)
            export_folder = uigetdir(app.Video_path, 'Select Top folder');
            
            
            if ~isempty(export_folder) && any(export_folder)
                current_expe_from_analysis_struct = arrayfun(@(x) strsplit(x{1}.filenames{1}{1},'/'), app.Experiment_set, 'UniformOutput', false);
                current_expe_from_analysis_struct = cellfun(@(x) [strjoin(x(end-4:end-3),'/'),'/'], current_expe_from_analysis_struct, 'UniformOutput', false);
                
                for experiment = app.ExperimentsListBox.Value                    
                    export_expe_folder = [export_folder, '/', experiment{1}];
                    id_from_analysis_struct = find(ismember(current_expe_from_analysis_struct, experiment{1}));
                    
                    if isdir([export_folder, '/', experiment{1}])
                        %% Get list of data folder on the export side
                        expe_folder = strrep([export_folder, '/', experiment{1}],'\','/');
                        data_folders = dir([expe_folder,'/*-*-*']);
                        data_folders = data_folders([data_folders.isdir]); % keep just folders
                        data_folders = {data_folders.name}';
                        
                        %% Get list of videos
                        match = ismember(app.full_table(:,9),experiment);
                        list = app.full_table(match,3);
                        
                        for rec_nb = 1:numel(list')
                            rec = list(rec_nb);
                            [~, best] = min(abs(datenum(data_folders,'HH-mm-ss') - datenum(rec,'HH_mm_ss')));
                            export_data_folder = [export_expe_folder,'/' data_folders{best, :},'/'];
                            
                            MI_data = {};
                            for video_type = 1:numel(app.Experiment_set.experiments(id_from_analysis_struct).motion_indexes)
                                data = cat(3, app.Experiment_set.experiments(id_from_analysis_struct).motion_indexes{video_type}{rec_nb}{:});
                                MI_data{video_type} = data;
                                figure(123);cla();plot(squeeze(MI_data{video_type}(:,2,:)), squeeze(MI_data{video_type}(:,1,:)));
                                pause(0.3);
                            end
                            save([export_data_folder, 'motion_index.mat'],'MI_data');
                        end
                        
                    end
                end
            end
        end

        % Value changed function: RecoverinterruptedanalysisButton
        function RecoverinterruptedanalysisButtonValueChanged(app, event)
            result = evalin('base','analysis_temp_backup');
            try
                temp_subset_expe_idx = evalin('base','temp_subset_expe_idx');
            catch
                temp_subset_expe_idx = 1:numel(app.Experiment_set.experiments);
            end
            app.Experiment_set.experiments(temp_subset_expe_idx) = result.experiments;
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: OpenFolderButton
        function OpenFolderButtonPushed(app, event)
            winopen(app.current_selection_folder);
        end

        % Button pushed function: OpenVideoButton
        function OpenVideoButtonPushed(app, event)
            if isempty(app.current_selection_idx)
                error_box('highlight the video type you want to open')
            else                
                if isfile(app.current_selection_file{app.current_selection_idx})
                    winopen(app.current_selection_file{app.current_selection_idx});
                end   
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            fold = [app.Video_path,strjoin(app.UITable.Data(event.Indices(1),1:2),'/'),'/*',app.UITable.Data{event.Indices(1),3},'*/'];
            files = dir([fold, '**/*.avi']);
            fold = files(1).folder;            
            app.current_selection_file = cellfun(@(a,b) strjoin({a,b},'/'), {files.folder}', {files.name}', 'UniformOutput', false);
            app.current_selection_folder = fold;
            idx = event.Indices(2)-3;
            if idx < 1 || idx > 3
                idx = 3;
            end
            list = app.UITable.ColumnName(4:6)';
            app.current_selection_idx = find(contains(app.current_selection_file, list{idx}));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BehaviourToolboxUIFigure = uifigure;
            app.BehaviourToolboxUIFigure.Name = 'Behaviour Toolbox';
            app.LoadExistingAnalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.LoadExistingAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingAnalysisButtonPushed, true);
            app.LoadExistingAnalysisButton.Text = 'Load Existing Analysis';
            app.StartNewAnalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.StartNewAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewAnalysisButtonPushed, true);
            app.StartNewAnalysisButton.Text = 'Start New Analysis';
            app.SaveAnalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.SaveAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisButtonPushed, true);
            app.SaveAnalysisButton.Enable = 'off';
            app.SaveAnalysisButton.Text = 'Save Analysis';
            app.UITable = uitable(app.BehaviourToolboxUIFigure);
            app.UITable.ColumnName = {'Day'; 'Experiment'; 'Recording'; 'Eye'; 'Body'; 'Whisker'; 'selected MI'; 'analysed'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectBrowseROIsButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.SelectBrowseROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBrowseROIsButtonPushed, true);
            app.SelectBrowseROIsButton.Enable = 'off';
            app.SelectBrowseROIsButton.Text = 'Select/Browse ROIs';
            app.ShowAnalyzeButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.ShowAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAnalyzeButtonPushed, true);
            app.ShowAnalyzeButton.Enable = 'off';
            app.ShowAnalyzeButton.Text = 'Show/Analyze';
            app.RecalculateallCheckBox = uicheckbox(app.BehaviourToolboxUIFigure);
            app.RecalculateallCheckBox.Enable = 'off';
            app.RecalculateallCheckBox.Text = 'Recalculate all';
            app.UpdateTableButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Enable = 'off';
            app.UpdateTableButton.Text = 'Update Table';
            app.PauseforeachexperimentCheckBox = uicheckbox(app.BehaviourToolboxUIFigure);
            app.PauseforeachexperimentCheckBox.Enable = 'off';
            app.PauseforeachexperimentCheckBox.Text = 'Pause for each experiment';
            app.ExperimentsListBoxLabel = uilabel(app.BehaviourToolboxUIFigure);
            app.ExperimentsListBoxLabel.Enable = 'off';
            app.ExperimentsListBoxLabel.Text = 'Experiments';
            app.ExperimentsListBox = uilistbox(app.BehaviourToolboxUIFigure);
            app.ExperimentsListBox.Items = {};
            app.ExperimentsListBox.Multiselect = 'on';
            app.ExperimentsListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentsListBoxValueChanged, true);
            app.ExperimentsListBox.Enable = 'off';
            app.ExperimentsListBox.Value = {};
            app.RefreshFilelistButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.RefreshFilelistButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshFilelistButtonPushed, true);
            app.RefreshFilelistButton.Text = 'Refresh File list';
            app.ExportToMatlabanalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.ExportToMatlabanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ExportToMatlabanalysisButtonPushed, true);
            app.ExportToMatlabanalysisButton.Enable = 'off';
            app.ExportToMatlabanalysisButton.Text = 'Export To Matlab analysis';
            app.RecoverinterruptedanalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'state');
            app.RecoverinterruptedanalysisButton.ValueChangedFcn = createCallbackFcn(app, @RecoverinterruptedanalysisButtonValueChanged, true);
            app.RecoverinterruptedanalysisButton.Text = 'Recover interrupted analysis';
            app.IfyoupressedCtrlCbutwantrecoverwhateverwasalreadyanalysedLabel = uilabel(app.BehaviourToolboxUIFigure);
            app.IfyoupressedCtrlCbutwantrecoverwhateverwasalreadyanalysedLabel.Text = {'If you pressed Ctrl+C but want '; 'recover whatever was already'; ' analysed'};
            app.OpenFolderButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.OpenFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderButtonPushed, true);
            app.OpenFolderButton.Text = 'Open Folder';
            app.OpenVideoButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.OpenVideoButton.ButtonPushedFcn = createCallbackFcn(app, @OpenVideoButtonPushed, true);
            app.OpenVideoButton.Text = 'Open Video';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Behaviour_analysis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BehaviourToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BehaviourToolboxUIFigure)
        end
    end
end